classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        betaEditField             matlab.ui.control.NumericEditField
        betaEditFieldLabel        matlab.ui.control.Label
        alphaEditField            matlab.ui.control.NumericEditField
        alphaEditFieldLabel       matlab.ui.control.Label
        thetaEditField            matlab.ui.control.NumericEditField
        thetaEditFieldLabel       matlab.ui.control.Label
        XEditField_6              matlab.ui.control.NumericEditField
        XEditField_5              matlab.ui.control.NumericEditField
        XEditField_4              matlab.ui.control.NumericEditField
        XEditField_3              matlab.ui.control.NumericEditField
        XEditField_2              matlab.ui.control.NumericEditField
        XEditField_1              matlab.ui.control.NumericEditField
        XLabel                    matlab.ui.control.Label
        PEditField_6              matlab.ui.control.NumericEditField
        PEditField_5              matlab.ui.control.NumericEditField
        PEditField_4              matlab.ui.control.NumericEditField
        PEditField_3              matlab.ui.control.NumericEditField
        PEditField_2              matlab.ui.control.NumericEditField
        PEditField_1              matlab.ui.control.NumericEditField
        PLabel                    matlab.ui.control.Label
        dEditField                matlab.ui.control.NumericEditField
        dEditFieldLabel           matlab.ui.control.Label
        cEditField                matlab.ui.control.NumericEditField
        cEditFieldLabel           matlab.ui.control.Label
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        EnterButton               matlab.ui.control.Button
        nrvarEditField            matlab.ui.control.NumericEditField
        nrvarEditFieldLabel       matlab.ui.control.Label
        pEditField                matlab.ui.control.NumericEditField
        pEditFieldLabel           matlab.ui.control.Label
        nEditField                matlab.ui.control.NumericEditField
        nEditFieldLabel           matlab.ui.control.Label
        DistribuiileListBox       matlab.ui.control.ListBox
        DistribuiileListBoxLabel  matlab.ui.control.Label
        CenterPanel               matlab.ui.container.Panel
        PLOTLabel                 matlab.ui.control.Label
        INFOLabel                 matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        
        function Bernoulli(app)
            p=app.pEditField.Value;
        end
        
        function Binomial(app)
            p=app.pEditField.Value;
            n=app.nEditField.Value;
        end
        
        function Geometric(app)
            p=app.pEditField.Value;
        end

        
        function Uniform(app)
            a=app.aEditField.Value;
            b=app.bEditField.Value;
        end
        
        function Non_uniform(app)
            P(1)=app.PEditField_1.Value;
            P(2)=app.PEditField_2.Value;
            P(3)=app.PEditField_3.Value;
            P(4)=app.PEditField_4.Value;
            P(5)=app.PEditField_5.Value;
            P(6)=app.PEditField_6.Value;
            X(1)=app.XEditField_1.Value;
            X(2)=app.XEditField_2.Value;
            X(3)=app.XEditField_3.Value;
            X(4)=app.XEditField_4.Value;
            X(5)=app.XEditField_5.Value;
            X(6)=app.XEditField_6.Value;
        end
        
        function Exponential(app)
            theta=app.thetaEditField.Value;
        end
        
        function Pareto(app)
            alpha= app.alphaEditField.Value;
            beta = app.betaEditField.Value;
        end
        
        function Point_in_rectangle(app)
            a=app.aEditField.Value;
            b=app.bEditField.Value;
            c=app.cEditField.Value;
            d=app.dEditField.Value;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            value = app.DistribuiileListBox.Value;
            switch value
                case 'Bernoulli'
                    Bernoulli(app);
            end
        end

        % Value changed function: DistribuiileListBox
        function DistribuiileListBoxValueChanged(app, event)
            value = app.DistribuiileListBox.Value;
            switch value
                case 'Bernoulli'
                    
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.pEditField.Visible = 'on';
                    app.pEditFieldLabel.Visible = 'on';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                   
                case 'Binomial'
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.nEditField.Visible='on';
                    app.nEditFieldLabel.Visible='on';
                    app.aEditField.Visible = 'off';
                    app.pEditFieldLabel.Visible = 'on';
                    app.pEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                case 'Geometric'
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.pEditField.Visible = 'on';
                    app.pEditFieldLabel.Visible = 'on';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                case 'Uniform'
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'on';
                    app.bEditField.Visible = 'on';
                    app.bEditFieldLabel.Visible = 'on';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                case 'Non-uniform'
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.PLabel.Visible = 'on';
                    app.PEditField_1.Visible = 'on';
                    app.PEditField_2.Visible = 'on';
                    app.PEditField_3.Visible = 'on';
                    app.PEditField_4.Visible = 'on';
                    app.PEditField_5.Visible = 'on';
                    app.PEditField_6.Visible = 'on';
                    app.XLabel.Visible = 'on';
                    app.XEditField_1.Visible = 'on';
                    app.XEditField_2.Visible = 'on';
                    app.XEditField_3.Visible = 'on';
                    app.XEditField_4.Visible = 'on';
                    app.XEditField_5.Visible = 'on';
                    app.XEditField_6.Visible = 'on';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                case 'Exponential'
                    app.thetaEditField.Visible='on';
                    app.thetaEditFieldLabel.Visible='on';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';
                case 'Pareto'
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'off';
                    app.aEditFieldLabel.Visible = 'off';
                    app.bEditField.Visible = 'off';
                    app.bEditFieldLabel.Visible = 'off';
                    app.cEditField.Visible = 'off';
                    app.cEditFieldLabel.Visible = 'off';
                    app.dEditField.Visible = 'off';
                    app.dEditFieldLabel.Visible = 'off';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.alphaEditField.Visible = 'on';
                    app.alphaEditFieldLabel.Visible = 'on';
                    app.betaEditField.Visible = 'on';
                    app.betaEditFieldLabel.Visible = 'on';
                case 'Point in rectangle'
                    app.thetaEditField.Visible='off';
                    app.thetaEditFieldLabel.Visible='off';
                    app.nEditField.Visible='off';
                    app.nEditFieldLabel.Visible='off';
                    app.pEditField.Visible='off';
                    app.pEditFieldLabel.Visible='off';
                    app.aEditField.Visible = 'on';
                    app.aEditFieldLabel.Visible = 'on';
                    app.bEditField.Visible = 'on';
                    app.bEditFieldLabel.Visible = 'on';
                    app.cEditField.Visible = 'on';
                    app.cEditFieldLabel.Visible = 'on';
                    app.dEditField.Visible = 'on';
                    app.dEditFieldLabel.Visible = 'on';
                    app.PLabel.Visible = 'off';
                    app.PEditField_1.Visible = 'off';
                    app.PEditField_2.Visible = 'off';
                    app.PEditField_3.Visible = 'off';
                    app.PEditField_4.Visible = 'off';
                    app.PEditField_5.Visible = 'off';
                    app.PEditField_6.Visible = 'off';
                    app.XLabel.Visible = 'off';
                    app.XEditField_1.Visible = 'off';
                    app.XEditField_2.Visible = 'off';
                    app.XEditField_3.Visible = 'off';
                    app.XEditField_4.Visible = 'off';
                    app.XEditField_5.Visible = 'off';
                    app.XEditField_6.Visible = 'off';
                    app.alphaEditField.Visible = 'off';
                    app.alphaEditFieldLabel.Visible = 'off';
                    app.betaEditField.Visible = 'off';
                    app.betaEditFieldLabel.Visible = 'off';

            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x', 449};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DistribuiileListBoxLabel = uilabel(app.LeftPanel);
            app.DistribuiileListBoxLabel.Text = 'Distribuțiile';
            app.DistribuiileListBox = uilistbox(app.LeftPanel);
            app.DistribuiileListBox.Items = {'Bernoulli', 'Geometric', 'Binomial', 'Uniform', 'Non-uniform', 'Exponential', 'Pareto', 'Point in rectangle'};
            app.DistribuiileListBox.ValueChangedFcn = createCallbackFcn(app, @DistribuiileListBoxValueChanged, true);
            app.DistribuiileListBox.Value = 'Bernoulli';
            app.nEditFieldLabel = uilabel(app.LeftPanel);
            app.nEditFieldLabel.Visible = 'off';
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.LeftPanel, 'numeric');
            app.nEditField.Visible = 'off';
            app.pEditFieldLabel = uilabel(app.LeftPanel);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pEditField.Limits = [0 Inf];
            app.nrvarEditFieldLabel = uilabel(app.LeftPanel);
            app.nrvarEditFieldLabel.Text = 'nr var';
            app.nrvarEditField = uieditfield(app.LeftPanel, 'numeric');
            app.nrvarEditField.Limits = [1 100];
            app.nrvarEditField.RoundFractionalValues = 'on';
            app.nrvarEditField.Value = 1;
            app.EnterButton = uibutton(app.LeftPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Visible = 'off';
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.Limits = [-100 100];
            app.aEditField.Visible = 'off';
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.Limits = [-100 100];
            app.bEditField.Visible = 'off';
            app.cEditFieldLabel = uilabel(app.LeftPanel);
            app.cEditFieldLabel.Visible = 'off';
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.LeftPanel, 'numeric');
            app.cEditField.Limits = [-100 100];
            app.cEditField.Visible = 'off';
            app.dEditFieldLabel = uilabel(app.LeftPanel);
            app.dEditFieldLabel.Visible = 'off';
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dEditField.Limits = [-100 100];
            app.dEditField.RoundFractionalValues = 'on';
            app.dEditField.Visible = 'off';
            app.PLabel = uilabel(app.LeftPanel);
            app.PLabel.Visible = 'off';
            app.PLabel.Text = 'P:';
            app.PEditField_1 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_1.Visible = 'off';
            app.PEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_2.Visible = 'off';
            app.PEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_3.Visible = 'off';
            app.PEditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_4.Visible = 'off';
            app.PEditField_5 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_5.Visible = 'off';
            app.PEditField_6 = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField_6.Visible = 'off';
            app.XLabel = uilabel(app.LeftPanel);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X:';
            app.XEditField_1 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_1.Visible = 'off';
            app.XEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_2.Visible = 'off';
            app.XEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_3.Visible = 'off';
            app.XEditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_4.Visible = 'off';
            app.XEditField_5 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_5.Visible = 'off';
            app.XEditField_6 = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField_6.Visible = 'off';
            app.thetaEditFieldLabel = uilabel(app.LeftPanel);
            app.thetaEditFieldLabel.Visible = 'off';
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.thetaEditField.Limits = [0 Inf];
            app.thetaEditField.Visible = 'off';
            app.alphaEditFieldLabel = uilabel(app.LeftPanel);
            app.alphaEditFieldLabel.Visible = 'off';
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.alphaEditField.Visible = 'off';
            app.betaEditFieldLabel = uilabel(app.LeftPanel);
            app.betaEditFieldLabel.Visible = 'off';
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.betaEditField.Visible = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Histograma')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.INFOLabel = uilabel(app.CenterPanel);
            app.INFOLabel.Text = 'INFO';
            app.PLOTLabel = uilabel(app.CenterPanel);
            app.PLOTLabel.Text = 'PLOT';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Densitate de probabilitate')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Functia de repartitie')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end